classdef HomeScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ExitButton          matlab.ui.control.Button
        InstructionsButton  matlab.ui.control.Button
        PLAYButton          matlab.ui.control.Button
        Image               matlab.ui.control.Image
        instructionsbox     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            REALblackjack;
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
            instructions = {
            'Objective:'
            'The goal is to have a hand value closer to 21.'
            'Dealing:'
            'Players and the dealer get two cards.'
            'One of the dealer''s cards is faceup (upcard), the other is face-down (hole card).'
            'Player Turn'
            'Players choose to "hit" (take a card) or "stand" (keep current total).'
            'Winning:'
            'Players win if they''re closer to 21 than the dealer. '};

        dlg = uiconfirm(app.UIFigure, instructions, 'Game Instructions','Icon','question');
   
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.instructionsbox = uilabel(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagesML', 'IMG_7018 Small.png');
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomeScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end