classdef REALblackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        HomeButton       matlab.ui.control.Button
        DealButton       matlab.ui.control.Button
        Stand3Button     matlab.ui.control.Button
        Stand2Button     matlab.ui.control.Button
        Stand1Button     matlab.ui.control.Button
        ResetButton      matlab.ui.control.Button
        GameOutcome      matlab.ui.control.Label
        Hit3Button       matlab.ui.control.Button
        Hit2Button       matlab.ui.control.Button
        Hit1Button       matlab.ui.control.Button
        PlayerScore_5    matlab.ui.control.Label
        PlayerScore_4    matlab.ui.control.Label
        PlayerScore_3    matlab.ui.control.Label
        PlayerScore_2    matlab.ui.control.Label
        PlayerScore_1    matlab.ui.control.Label
        ComputerScore_5  matlab.ui.control.Label
        ComputerScore_4  matlab.ui.control.Label
        ComputerScore_3  matlab.ui.control.Label
        ComputerScore_2  matlab.ui.control.Label
        ComputerScore_1  matlab.ui.control.Label
        PlayerScore      matlab.ui.control.Label
        ComputerScore    matlab.ui.control.Label
        Image2           matlab.ui.control.Image
        Round1Label      matlab.ui.control.Label
        Image            matlab.ui.control.Image
    end

    
    properties (Access = private)
        sumP
        sumC
        drawP1
        drawP2
        drawP3
        drawP4
        drawP5
        drawC1
        drawC2
        drawC3
        drawC4
        drawC5
        suit
        channelID = 2374028;
        userAPIKey = '3R1PMDZMKEF9JE3X';
        readKey = '8Z1HB6B02L2OYKAQ';
        writeKey = '16077FH694HYN8YM';
    end
    
    methods (Access = private)
        
        function sumP = calcScoreP(app)
            sumP = app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4 + app.drawP5;
             
        end
        
        function sumC = calcScoreC(app)
            sumC = app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5;
        end
        
        function suit = chooseSuit(~)
            suit = num2str(randi(1:4));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResetButton.Visible = 'off';

            pause(1)
            [u,v] = audioread("imagesML\startup.mp3");
            sound(u,v);

           
        end

        % Button pushed function: Hit1Button
        function Hit1ButtonPushed(app, event)
            app.drawP3 = randi([1,11]);
            app.drawC3 = randi([1,11]);

            app.PlayerScore_3.Text = [num2str(app.drawP3)];
            app.ComputerScore_3.Text = [num2str(app.drawC3)];


            app.Round1Label.Text = 'Round: 2';
            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2 + app.drawP3)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC2 + app.drawC3)];

            app.Hit1Button.Visible = 'off';
            app.Stand1Button.Visible = 'off';
        end

        % Button pushed function: Hit2Button
        function Hit2ButtonPushed(app, event)
            app.drawP4 = randi([1,11]);
            app.drawC4 = randi([1,11]);

            app.PlayerScore_4.Text = [num2str(app.drawP4)];
            app.ComputerScore_4.Text = [num2str(app.drawC4)];


            app.Round1Label.Text = 'Round: 3';
            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC2 + app.drawC3 + app.drawC4)];

            app.Hit2Button.Visible = 'off';
            app.Stand2Button.Visible = 'off';
        end

        % Button pushed function: Hit3Button
        function Hit3ButtonPushed(app, event)
            app.drawP5 = randi([1,11]);
            app.drawC5 = randi([1,11]);

            app.PlayerScore_5.Text = [num2str(app.drawP5)];
            app.ComputerScore_5.Text = [num2str(app.drawC5)];
            app.ComputerScore_1.Text = [num2str(app.drawC1)];

            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4 + app.drawP5)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5)];

            app.Round1Label.Text = 'Finish!';
            app.Hit3Button.Visible = 'off';
            app.Stand3Button.Visible = 'off';
            app.ResetButton.Visible = 'on';

            pause(1);
            thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 1, 'Values', app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4 + app.drawP5);
            pause(1);
            data = thingSpeakRead(app.channelID, 'ReadKey', app.readKey, 'Fields', 1);

            if abs((data) - 21) < abs((app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5) - 21)
                app.GameOutcome.Text = 'You Win!';
                [a,b] = audioread("imagesML\Victory.mp3");
                sound(a,b);
            elseif abs((data) - 21) > abs((app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5) - 21)
                app.GameOutcome.Text = 'You Lose!';
                [x,y] = audioread("imagesML\Loser.mp3");
                sound(x,y);
            else
                app.GameOutcome.Text = 'Tie!';
            end

            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

           app.DealButton.Visible = 'on';

           app.Hit1Button.Visible = 'on';
           app.Hit2Button.Visible = 'on';
           app.Hit3Button.Visible = 'on';


           app.Stand1Button.Visible = 'on';
           app.Stand2Button.Visible = 'on';
           app.Stand3Button.Visible = 'on';

           
           app.PlayerScore_1.Text = '';
           app.PlayerScore_2.Text = '';
           app.PlayerScore_3.Text = '';
           app.PlayerScore_4.Text = '';
           app.PlayerScore_5.Text = '';

           app.ComputerScore_1.Text = '';
           app.ComputerScore_2.Text = '';
           app.ComputerScore_3.Text = '';
           app.ComputerScore_4.Text = '';
           app.ComputerScore_5.Text = '';

           app.GameOutcome.Text = '';

           app.Round1Label.Text = 'Round: 1';
           app.PlayerScore.Text = 'Player Score: ';
           app.ComputerScore.Text = 'Computer Score: ';

           app.ResetButton.Visible = 'off';
        end

        % Button pushed function: Stand2Button
        function Stand2ButtonPushed(app, event)
            app.drawP4 = 0;
            app.drawC4 = 0;
            
            app.PlayerScore_4.Text = '';
            app.ComputerScore_4.Text = '';

            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC2 + app.drawC3 + app.drawC4)];
            
            app.Round1Label.Text = 'Round: 3';
            app.Hit2Button.Visible = 'off';
            app.Stand2Button.Visible = 'off';
        end

        % Button pushed function: Stand3Button
        function Stand3ButtonPushed(app, event)
            app.drawP5 = 0;
            app.drawC5 = 0;

            app.PlayerScore_5.Text = '';
            app.ComputerScore_5.Text = '';
            app.ComputerScore_1.Text = [num2str(app.drawC1)];

            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4 + app.drawP5)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5)];

            app.Round1Label.Text = 'Finish!';
            app.Hit3Button.Visible = 'off';
            app.Stand3Button.Visible = 'off';
            app.ResetButton.Visible = 'on';
            
            pause(1);
            thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey, 'Fields', 1, 'Values', app.drawP1 + app.drawP2 + app.drawP3 + app.drawP4 + app.drawP5);
            pause(1);
            data = thingSpeakRead(app.channelID, 'ReadKey', app.readKey, 'Fields', 1);

            if abs((data) - 21) < abs((app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5) - 21)
                app.GameOutcome.Text = 'You Win!';
                [a,b] = audioread("imagesML\Victory.mp3");
                sound(a,b);
            elseif abs((data) - 21) > abs((app.drawC1 + app.drawC2 + app.drawC3 + app.drawC4 + app.drawC5) - 21)
                app.GameOutcome.Text = 'You Lose!';
                [x,y] = audioread("imagesML\Loser.mp3");
                sound(x,y);
            else
                app.GameOutcome.Text = 'Tie!';
            end
            
            
        end

        % Button pushed function: Stand1Button
        function Stand1ButtonPushed(app, event)
            app.drawP3 = 0;
            app.drawC3 = 0;
            
            app.PlayerScore_3.Text = '';
            app.ComputerScore_3.Text = '';

            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2 + app.drawP3)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC2 + app.drawC3)];
            
            app.Round1Label.Text = 'Round: 2';
            app.Hit1Button.Visible = 'off';
            app.Stand1Button.Visible = 'off';
        end

        % Button pushed function: DealButton
        function DealButtonPushed(app, event)
            app.drawP1 = randi([1,11]);
            app.drawC1 = randi([1,11]);
            app.PlayerScore_1.Text = [num2str(app.drawP1)];
            app.ComputerScore_1.Text = 'Hole';
            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1)];

            app.drawP2 = randi([1,11]);
            app.drawC2 = randi([1,11]);
            app.PlayerScore_2.Text = [num2str(app.drawP2)];
            app.ComputerScore_2.Text = [num2str(app.drawC2)];
            app.PlayerScore.Text = ['Player Score: ', num2str(app.drawP1 + app.drawP2)];
            app.ComputerScore.Text = ['Computer Score: ', num2str(app.drawC2)];

            app.DealButton.Visible = 'off';
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagesML', 'card game green.jpg');
            app.Round1Label = uilabel(app.UIFigure);
            app.Round1Label.Text = 'Round: 1';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'imagesML', 'red-rounded-rectangle-button-yellow-border-md.png');
            app.ComputerScore = uilabel(app.UIFigure);
            app.ComputerScore.Text = 'Computer Score: ';
            app.PlayerScore = uilabel(app.UIFigure);
            app.PlayerScore.Text = 'Player Score:';
            app.ComputerScore_1 = uilabel(app.UIFigure);
            app.ComputerScore_1.Text = '';
            app.ComputerScore_2 = uilabel(app.UIFigure);
            app.ComputerScore_2.Text = '';
            app.ComputerScore_3 = uilabel(app.UIFigure);
            app.ComputerScore_3.Text = '';
            app.ComputerScore_4 = uilabel(app.UIFigure);
            app.ComputerScore_4.Text = '';
            app.ComputerScore_5 = uilabel(app.UIFigure);
            app.ComputerScore_5.Text = '';
            app.PlayerScore_1 = uilabel(app.UIFigure);
            app.PlayerScore_1.Text = '';
            app.PlayerScore_2 = uilabel(app.UIFigure);
            app.PlayerScore_2.Text = '';
            app.PlayerScore_3 = uilabel(app.UIFigure);
            app.PlayerScore_3.Text = '';
            app.PlayerScore_4 = uilabel(app.UIFigure);
            app.PlayerScore_4.Text = '';
            app.PlayerScore_5 = uilabel(app.UIFigure);
            app.PlayerScore_5.Text = '';
            app.Hit1Button = uibutton(app.UIFigure, 'push');
            app.Hit1Button.ButtonPushedFcn = createCallbackFcn(app, @Hit1ButtonPushed, true);
            app.Hit1Button.Text = 'Hit 1';
            app.Hit2Button = uibutton(app.UIFigure, 'push');
            app.Hit2Button.ButtonPushedFcn = createCallbackFcn(app, @Hit2ButtonPushed, true);
            app.Hit2Button.Text = 'Hit 2';
            app.Hit3Button = uibutton(app.UIFigure, 'push');
            app.Hit3Button.ButtonPushedFcn = createCallbackFcn(app, @Hit3ButtonPushed, true);
            app.Hit3Button.Text = 'Hit 3';
            app.GameOutcome = uilabel(app.UIFigure);
            app.GameOutcome.Text = '';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Stand1Button = uibutton(app.UIFigure, 'push');
            app.Stand1Button.ButtonPushedFcn = createCallbackFcn(app, @Stand1ButtonPushed, true);
            app.Stand1Button.Text = 'Stand 1';
            app.Stand2Button = uibutton(app.UIFigure, 'push');
            app.Stand2Button.ButtonPushedFcn = createCallbackFcn(app, @Stand2ButtonPushed, true);
            app.Stand2Button.Text = 'Stand 2';
            app.Stand3Button = uibutton(app.UIFigure, 'push');
            app.Stand3Button.ButtonPushedFcn = createCallbackFcn(app, @Stand3ButtonPushed, true);
            app.Stand3Button.Text = 'Stand 3';
            app.DealButton = uibutton(app.UIFigure, 'push');
            app.DealButton.ButtonPushedFcn = createCallbackFcn(app, @DealButtonPushed, true);
            app.DealButton.Text = 'Deal';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REALblackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end